classdef mockCOMP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DriverWeightkgEditFieldLabel  matlab.ui.control.Label
        DriverWeightkgEditField       matlab.ui.control.NumericEditField
        LambdaEditFieldLabel          matlab.ui.control.Label
        LambdaEditField               matlab.ui.control.NumericEditField
        DeltachangeEditFieldLabel     matlab.ui.control.Label
        DeltachangeEditField          matlab.ui.control.NumericEditField
        SweepValuesCheckBox           matlab.ui.control.CheckBox
        SweptParameterListBoxLabel    matlab.ui.control.Label
        SweptParameterListBox         matlab.ui.control.ListBox
        RunSimulationButton           matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        WUFRMockCOMPLabel             matlab.ui.control.Label
        VehicleSelectDropDownLabel    matlab.ui.control.Label
        VehicleSelectDropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        vehiclefile = ''; % Description
        driverWeight = 0;
        lambda = 1.0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get list of files from the directory
            files = dir('./OpenVEHICLEVehicles/*.mat'); % Assuming .mat files
            filenames = {files.name}; % Extracting names into a cell array
        
            % Set the filenames as items in the DropDown
            app.VehicleSelectDropDown.Items = filenames;
            
            app.DeltachangeEditField.Enable = 'off';
            app.SweptParameterListBox.Enable = 'off';
        end

        % Callback function
        function SelectVehicleButtonPushed(app, event)
            app.vehiclefile = uigetfile('*.mat', 'Select a Vehicle File', './OpenVEHICLEVehicles');
            
        end

        % Value changed function: DriverWeightkgEditField
        function DriverWeightkgEditFieldValueChanged(app, event)
            
        end

        % Value changed function: LambdaEditField
        function LambdaEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            % Initialize the progress bar
            progressBar = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Running simulations...');
            progressBar.Value = 0; % Initial value
            
            % mockCOMP      
            app.vehiclefile = app.VehicleSelectDropDown.Value;
            app.driverWeight = app.DriverWeightkgEditField.Value;
            app.lambda = app.LambdaEditField.Value;
                
            veh = load(app.vehiclefile);   
            veh.M = veh.M + app.driverWeight;
            
            
            % Run Endurance
            tr = load("./OpenTRACKTracks/OpenTRACK_Michigan Endurance_Closed_Forward.mat");
            progressBar.Value = 0.25; % Update progress bar to 25%
            progressBar.Message = 'Endurance done. Running Acceleration...';
            [sim] = simulate(veh,tr) ;
                co2_produced = sim.co2_produced.data;
                tMin = 1360.98; % 2023-University of Texas-Arlington
                co2Min = 5.075; % 2023-South Dakota State University
                tCo2Min = 1953.1; %time of 1st place
                tMax = 1973.42;
                co2Max = 13.213;
                lapNum = 10;
                laptime = sim.laptime.data*app.lambda;
                if (laptime*10)<tMin
                    tMin = laptime*10;
                end
                if co2_produced < co2Min
                    co2Min = co2_produced;
                    tCo2Min = laptime * 10;
                end
                effFactor = (tMin/lapNum)/(laptime)*(co2Min/lapNum)/(co2_produced/lapNum);
                effFactorMin = (tMin/lapNum)/(tMax/lapNum)*(co2Min/lapNum)/(co2Max/lapNum);
                effFactorMax = (tMin/lapNum)/(tCo2Min/lapNum)*(co2Min/lapNum)/(co2Min/lapNum);
                if effFactor > effFactorMax
                    effFactorMax = effFactor;
                end
                efficiencyScore = 100 * (effFactorMin/effFactor - 1)/(effFactorMin/effFactorMax - 1);
                
                % Endurance Score Calculations
            
                enduranceScore = 0;
                % Calculate Corrected Time
                DOO = 0;
                OC = 0;
                
                CorrectedTime = laptime + (DOO * 2) + (OC * 20);
                
                % Calculate Tmax
                Tmax = 1.45 * tMin;
                
                % Calculate Endurance Score based on the provided rules
                if CorrectedTime < Tmax
                    enduranceScore = 250 * ((Tmax / CorrectedTime) - 1) / ((Tmax / tMin) - 1) + 25;
                else
                    enduranceScore = 25;
                end
            
            
            result1 = {
                '========== ENDURANCE AND EFFICIENCY ==========';
                ['Laptime:  ',num2str(laptime,'%3.3f'),' [s]'];
                ['Endurance Score:  ',num2str(enduranceScore,'%3.3f'),' [pts]'];
                ['Efficiency Score:  ',num2str(efficiencyScore,'%3.3f'),' [pts]'];
                ' ';
                };


            % Run Acceleration
            
            time = accelerationSimulation(veh, 1)*app.lambda;
            DOO = 0; % Example number of cones knocked down
            points = accelerationScore(time, DOO);
            result2 = {
                '========== ACCELERATION ==========';
                ['Finished in: ', num2str(time),' [s]'];
                ['Acceleration Score: ', num2str(points),' [pts]'];
                ' ';
            };


            % Run Autocross
            progressBar.Value = 0.50; % Update progress bar to 50%
            progressBar.Message = 'Acceleration done. Running Autocross...';
            tr = load("./OpenTRACKTracks/OpenTRACK_Autocross_Open_Forward.mat");
            [sim] = simulate(veh,tr) ;
            
            Tmin = 45.886; % Minimum time from the data
            Tmax = 1.45 * Tmin; % 145% of Tmin
            runTime = sim.laptime.data/10*app.lambda; % Example run time
            DOO = 0; % Example number of displaced cones
            OC = 0; % Example number of off courses
            
            score = autocrossScore(runTime, DOO, OC, Tmin, Tmax);
            result3 = {
                '========== AUTOCROSS ==========';
                ['Laptime:  ',num2str(sim.laptime.data/10*app.lambda,'%3.3f'),' [s]'];
                ['Autocross Score:  ',num2str(score,'%3.3f'),' [pts]'];
                ' ';
            };


            % Run Skidpad
            progressBar.Value = 0.75; % Update progress bar to 75%
            progressBar.Message = 'Autocross done. Running Skidpad...';
            tr = load("./OpenTRACKTracks/OpenTRACK_Skidpad_Closed_Forward.mat");
            [sim] = simulate(veh,tr) ;
            
            time = sim.laptime.data/40*app.lambda;
            DOO = 0;              % Number of DOO (including entry and exit gate cones)
            Tmin = 4.908;         % Lowest Corrected Time recorded for any team
            if time < Tmin
                Tmin = time;
            end
            
            score = skidpadScore(time, DOO, Tmin);
          
            result4 = {
                '========== SKIDPAD ==========';
                ['Skidpad Score: ', num2str(score)];
                ['Laptime:  ',num2str(time,'%3.3f'),' [s]'];
                ' ';
            };
            progressBar.Value = 1.0; % Update progress bar to 100%
            progressBar.Message = 'All simulations completed!';


            function score = autocrossScore(runTime, DOO, OC, Tmin, Tmax)
                % Calculate Corrected Time
                correctedTime = runTime + (DOO * 2) + (OC * 20);
                
                % Calculate Autocross Score based on Corrected Time and Tmin, Tmax
                if correctedTime < Tmax
                    score = 118.5 * ((Tmax / correctedTime) - 1)/((Tmax / Tmin) - 1) + 6.5;
                else
                    score = 6.5;
                end
            end
            
            function score = skidpadScore(time, DOO, Tmin)
                % Constants
                Tmax = 1.25 * Tmin;
                
                % Corrected Time Calculation
                correctedTime = (time) + (DOO * 0.125);
                
                % Score Calculation
                if correctedTime < Tmax
                    score = 71.5 * ((Tmax / correctedTime)^2 - 1)/(((Tmax / Tmin)^2 - 1)) + 3.5 ;
                  
                else
                    score = 3.5;
                end
            end


            results = [result1; result2; result3; result4];
            app.TextArea.Value = results;
        end

        % Value changed function: VehicleSelectDropDown
        function VehicleSelectDropDownValueChanged(app, event)
            
    
        end

        % Value changed function: SweepValuesCheckBox
        function SweepValuesCheckBoxValueChanged(app, event)
            value = app.SweepValuesCheckBox.Value;
    
            if value % If the checkbox is checked
                app.DeltachangeEditField.Enable = 'on';
                app.SweptParameterListBox.Enable = 'on';
                % ... enable other related components ...
            else % If the checkbox is unchecked
                app.DeltachangeEditField.Enable = 'off';
                app.SweptParameterListBox.Enable = 'off';
                % ... disable other related components ...
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DriverWeightkgEditFieldLabel = uilabel(app.UIFigure);
            app.DriverWeightkgEditFieldLabel.Text = {'Driver Weight (kg)'; ''};
            app.DriverWeightkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.DriverWeightkgEditField.ValueChangedFcn = createCallbackFcn(app, @DriverWeightkgEditFieldValueChanged, true);
            app.DriverWeightkgEditField.Value = 68;
            app.LambdaEditFieldLabel = uilabel(app.UIFigure);
            app.LambdaEditFieldLabel.Text = {'Lambda'; ''};
            app.LambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LambdaEditField.Limits = [0.1 Inf];
            app.LambdaEditField.ValueChangedFcn = createCallbackFcn(app, @LambdaEditFieldValueChanged, true);
            app.LambdaEditField.Value = 1;
            app.DeltachangeEditFieldLabel = uilabel(app.UIFigure);
            app.DeltachangeEditFieldLabel.Text = 'Delta change';
            app.DeltachangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltachangeEditField.Value = 1;
            app.SweepValuesCheckBox = uicheckbox(app.UIFigure);
            app.SweepValuesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SweepValuesCheckBoxValueChanged, true);
            app.SweepValuesCheckBox.Text = {'Sweep Values?'; ''};
            app.SweptParameterListBoxLabel = uilabel(app.UIFigure);
            app.SweptParameterListBoxLabel.Text = 'Swept Parameter';
            app.SweptParameterListBox = uilistbox(app.UIFigure);
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Tag = 'simOutput';
            app.TextArea.Editable = 'off';
            app.WUFRMockCOMPLabel = uilabel(app.UIFigure);
            app.WUFRMockCOMPLabel.Text = 'WUFR MockCOMP';
            app.VehicleSelectDropDownLabel = uilabel(app.UIFigure);
            app.VehicleSelectDropDownLabel.Text = {'Vehicle Select'; ''};
            app.VehicleSelectDropDown = uidropdown(app.UIFigure);
            app.VehicleSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleSelectDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mockCOMP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end